classdef sdsp_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenbinfileMenu               matlab.ui.container.Menu
        ResetUIMenu                   matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        FileconfigMenu                matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        AboutsdspMenu                 matlab.ui.container.Menu
        CreatedbyMS29thJune2024Label  matlab.ui.control.Label
        RestoreOriginalFsButton       matlab.ui.control.Button
        Resample2XButton              matlab.ui.control.Button
        OperationsButtonGroup         matlab.ui.container.ButtonGroup
        CM80PlotButton                matlab.ui.control.ToggleButton
        CM40PlotButton                matlab.ui.control.ToggleButton
        CM20PlotButton                matlab.ui.control.ToggleButton
        CM21PlotButton                matlab.ui.control.ToggleButton
        FFTPlotButton                 matlab.ui.control.ToggleButton
        AbsTimePlotButton             matlab.ui.control.ToggleButton
        WelcometoSDSPAPPLabel         matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        BinfiledirpathEditField       matlab.ui.control.EditField
        BinfiledirpathEditFieldLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        o_x_IQ;
        x_IQ;
        o_fs;
        fs;
        fvector;
        currentflag = 1;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clear filepath dirpath;
            [filepath,dirpath] = uigetfile("*.bin","Please pick a bin file","MultiSelect","On");
            handle1 = uiprogressdlg(app.UIFigure,'Title',"Opening File... pls wait...'",'Message',"Opening File in Progress");
            try
                errorflag = 0;
                if ~iscell(filepath)
                    [app.x_IQ,app.fs] = binfileread(fullfile(dirpath,filepath));
                else
                    for idx = 1:length(filepath)
                        [x,filefs] = binfileread(fullfile(dirpath,filepath{idx}));
                        app.x_IQ = [app.x_IQ x(:).'];
                        if idx==length(filepath)
                            app.fs = filefs/length(filepath);
                        end
                    end
                end
                app.BinfiledirpathEditField.Value = fullfile(dirpath,filepath);
            catch
                errorflag = 1;
            end
            close(handle1);
            if errorflag
                handle2 = uialert(app.UIFigure,'The input file(s) cannot be opened!',"Error","Icon","error");
            else
                app.UIAxes2.Title.String = "Abs Time plot";
                app.UIAxes2.XLabel.String = "time(samples)";
                app.UIAxes2.YLabel.String = "Amplitude";
                plot(app.UIAxes2,(0:app.fs-1),abs(app.x_IQ));
                app.fvector = -app.fs/2:app.fs/length(app.x_IQ):app.fs/2-1/length(app.x_IQ);
                app.o_fs = app.fs; app.o_x_IQ = app.x_IQ;
                app.OperationsButtonGroup.Enable = "on";
                app.Resample2XButton.Enable = "on";
                app.RestoreOriginalFsButton.Enable = "on";
            end
        end

        % Selection changed function: OperationsButtonGroup
        function OperationsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperationsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Abs Time Plot"
                    if app.currentflag~=1
                        app.UIAxes2.Title.String = "Abs Time plot";
                        app.UIAxes2.XLabel.String = "time(samples)";
                        app.UIAxes2.YLabel.String = "Amplitude";
                        plot(app.UIAxes2,(0:app.fs-1),abs(app.x_IQ));
                        app.currentflag = 1;
                    end
                case "FFT Plot"
                    if app.currentflag~=2
                        app.UIAxes2.Title.String = "FFT plot";
                        app.UIAxes2.XLabel.String = "Frequency";
                        plot(app.UIAxes2,app.fvector,20*log10(fftshift(abs(fft(app.x_IQ)))));
                        app.currentflag = 2;
                    end
                case "CM21 Plot"
                    if app.currentflag~=3
                        app.UIAxes2.Title.String = "CM21 plot";
                        app.UIAxes2.XLabel.String = "Frequency";
                        plot(app.UIAxes2,app.fvector,20*log10(fftshift(abs(fft(abs(app.x_IQ))))));
                        app.currentflag = 3;
                    end
                case "CM20 Plot"
                    if app.currentflag~=4
                        app.UIAxes2.Title.String = "CM20 plot";
                        app.UIAxes2.XLabel.String = "Frequency";
                        plot(app.UIAxes2,app.fvector,20*log10(fftshift(abs(fft((app.x_IQ).^2)))));
                        app.currentflag = 4;
                    end
                case "CM40 Plot"
                    if app.currentflag~=5
                        app.UIAxes2.Title.String = "CM40 plot";
                        app.UIAxes2.XLabel.String = "Frequency";
                        plot(app.UIAxes2,app.fvector,20*log10(fftshift(abs(fft((app.x_IQ).^4)))));
                        app.currentflag = 5;
                    end
                case "CM80 Plot"
                    if app.currentflag~=6
                        app.UIAxes2.Title.String = "CM80 plot";
                        app.UIAxes2.XLabel.String = "Frequency";
                        plot(app.UIAxes2,app.fvector,20*log10(fftshift(abs(fft((app.x_IQ).^8)))));
                        app.currentflag = 6;
                    end
            end
        end

        % Menu selected function: OpenbinfileMenu
        function OpenbinfileMenuSelected(app, event)
            ButtonPushed(app, event);
        end

        % Button pushed function: Resample2XButton
        function Resample2XButtonPushed(app, event)
            app.x_IQ = resample(app.x_IQ,2,1);
            app.fs = 2*app.fs;
            app.fvector = -app.fs/2:app.fs/length(app.x_IQ):app.fs/2-1/length(app.x_IQ);
            app.currentflag = 0;
            OperationsButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: RestoreOriginalFsButton
        function RestoreOriginalFsButtonPushed(app, event)
            app.x_IQ = app.o_x_IQ;
            app.fs = app.o_fs;
            app.fvector = -app.fs/2:app.fs/length(app.x_IQ):app.fs/2-1/length(app.x_IQ);
            app.currentflag = 0;
            OperationsButtonGroupSelectionChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenbinfileMenu = uimenu(app.FileMenu);
            app.OpenbinfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenbinfileMenuSelected, true);
            app.OpenbinfileMenu.Accelerator = 'O';
            app.OpenbinfileMenu.Text = 'Open binfile';
            app.ResetUIMenu = uimenu(app.FileMenu);
            app.ResetUIMenu.Accelerator = 'R';
            app.ResetUIMenu.Text = 'Reset UI';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.FileconfigMenu = uimenu(app.EditMenu);
            app.FileconfigMenu.Text = 'File config';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutsdspMenu = uimenu(app.AboutMenu);
            app.AboutsdspMenu.Text = 'About sdsp';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.BinfiledirpathEditFieldLabel = uilabel(app.UIFigure);
            app.BinfiledirpathEditFieldLabel.Text = 'Bin filedirpath';
            app.BinfiledirpathEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '..';
            app.WelcometoSDSPAPPLabel = uilabel(app.UIFigure);
            app.WelcometoSDSPAPPLabel.Text = 'Welcome to SDSP APP';
            app.OperationsButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationsButtonGroupSelectionChanged, true);
            app.OperationsButtonGroup.Enable = 'off';
            app.OperationsButtonGroup.Title = 'Operations';
            app.AbsTimePlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.AbsTimePlotButton.Text = 'Abs Time Plot';
            app.AbsTimePlotButton.Value = true;
            app.FFTPlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.FFTPlotButton.Text = 'FFT Plot';
            app.CM21PlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.CM21PlotButton.Text = 'CM21 Plot';
            app.CM20PlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.CM20PlotButton.Text = 'CM20 Plot';
            app.CM40PlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.CM40PlotButton.Text = 'CM40 Plot';
            app.CM80PlotButton = uitogglebutton(app.OperationsButtonGroup);
            app.CM80PlotButton.Text = 'CM80 Plot';
            app.Resample2XButton = uibutton(app.UIFigure, 'push');
            app.Resample2XButton.ButtonPushedFcn = createCallbackFcn(app, @Resample2XButtonPushed, true);
            app.Resample2XButton.Enable = 'off';
            app.Resample2XButton.Text = 'Resample 2X';
            app.RestoreOriginalFsButton = uibutton(app.UIFigure, 'push');
            app.RestoreOriginalFsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreOriginalFsButtonPushed, true);
            app.RestoreOriginalFsButton.Enable = 'off';
            app.RestoreOriginalFsButton.Text = 'Restore Original Fs';
            app.CreatedbyMS29thJune2024Label = uilabel(app.UIFigure);
            app.CreatedbyMS29thJune2024Label.Text = 'Created by MS: 29th June 2024';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sdsp_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end